classdef Main_BubCNN_TransferModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BubCNN_Transfer_Learning_Module  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        UIAxes                        matlab.ui.control.UIAxes
        Slider                        matlab.ui.control.Slider
        SaveandExitButton             matlab.ui.control.Button
        TippsPanel                    matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        TextAreaLabel                 matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        SaveLinesPointsButton         matlab.ui.control.Button
        LoadLinesPointsButton         matlab.ui.control.Button
        SelectBubblesButton           matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        xButton                       matlab.ui.control.Button
        xButton_2                     matlab.ui.control.Button
        Button_4                      matlab.ui.control.Button
        ellipseHEditFieldLabel        matlab.ui.control.Label
        ellipseHEditField             matlab.ui.control.EditField
        ellipseWEditFieldLabel        matlab.ui.control.Label
        ellipseWEditField             matlab.ui.control.EditField
        CropImageButton               matlab.ui.control.Button
        CreateTrainingDataSetsButton  matlab.ui.control.Button
        UsePrelabellingTraditionalImagingButton  matlab.ui.control.Button
        UsePrelabellingPrevBubCNNVersionButton  matlab.ui.control.Button
        StartTransferLearningButton   matlab.ui.control.Button
        Logo                          matlab.ui.control.Image
        HelpButton                    matlab.ui.control.Button
    end

    % Copyright (c) Institut für Industrieofenbau RWTH Aachen University  - All Rights Reserved
    % Unauthorized copying of this file, via any medium is strictly prohibited
    % Proprietary and confidential
    % Written by Tim Haas <haas@iob.rwth-aachen.de> and Christian Schubert, 2019
    
    
    properties (Access = private)
        filename
        images % Description
        no_images
        current_image
        ellipses
    end
    
    methods (Access = private)


        function update_slider(app)
            app.Slider.Limits=[1 app.no_images];
            app.Slider.Value = double(round(double(app.current_image)/app.no_images*100));
            
        end
        
        function update_frame_image(app)
            if app.no_images>1
                app.Slider.Limits=[1 app.no_images];
            else
                app.Slider.Limits=[0 app.no_images];
            end
            if app.no_images<10
                app.Slider.MajorTicks=[0:1:app.no_images];
                app.Slider.MinorTicks=app.Slider.MajorTicks;
            elseif app.no_images<10
                app.Slider.MajorTicks=[0:2:app.no_images];
                app.Slider.MinorTicks=[0:1:app.no_images];
            elseif app.no_images<20
                app.Slider.MajorTicks=[0:5:app.no_images];
                app.Slider.MinorTicks=[0:1:app.no_images];
            else
                app.Slider.MajorTicks=[0:10:app.no_images];
            end
            imshow(app.images(app.current_image).cdata, 'Parent', app.UIAxes);
            set(app.UIAxes,'visible','off');
            axis(app.UIAxes, 'square');
            axis(app.UIAxes, 'tight');
            
            hold(app.UIAxes,'on');
            if ~isempty(app.ellipses{app.current_image})
                for i = 1:1:size(app.ellipses{app.current_image},1)
                    x = app.ellipses{app.current_image}(i).Vertices(:,1)-20;
                    y = app.ellipses{app.current_image}(i).Vertices(:,2)-20;
                    plot(x,y,'-r','Parent', app.UIAxes)
                end
            end
            hold(app.UIAxes,'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            [FileName, PathName] = uigetfile({'*.png;*.jpg;*.bmp;*.tif;*.mp4;*.avi;*.mkv;*.mov'}, ...
                'Select image or video files','MultiSelect','on');
            error = false;
            
            if isequal(FileName,0)
                app.delete;
                
            else
                if iscell(FileName)
                    
                    for i = 1 : 1 : size(FileName,2)
                        if endsWith(FileName{i}, ".png") || endsWith(FileName{i}, ".bmp") || endsWith(FileName{i}, ".jpg") || endsWith(FileName{i}, ".jpeg") || endsWith(FileName{i}, ".tiff")
                            im = imread([PathName FileName{i}]);
                            app.images(i).cdata = im;%struct('cdata',im,'colormap',[]);
                            %todo error unequal image sizes
                        else
                            error = true;
                        end
                    end
                else
                    if endsWith(FileName, ".png") || endsWith(FileName, ".bmp") || endsWith(FileName, ".jpg") || endsWith(FileName, ".jpeg") || endsWith(FileName, ".tiff")|| endsWith(FileName, ".JPG")
                        isimage = true;
                        im = imread([PathName FileName]);
                        app.images = struct('cdata',im,'colormap',[]);
                        app.no_images
                        i = 1;
                    elseif endsWith(FileName, ".avi") || endsWith(FileName, ".mkv") || endsWith(FileName, ".mp4")|| endsWith(FileName, ".MOV")|| endsWith(FileName, ".mov")
                        ismovie = true;
                        vid = VideoReader([PathName FileName]);
                        vidWidth = vid.Width;
                        vidHeight = vid.Height;
                        app.images = repmat(struct('cdata', zeros(vidHeight,vidWidth,3,'uint8'),'colormap',[]), vid.NumberOfFrames, 1);
                        vid = VideoReader([PathName FileName]);
                        i = 1;
                        while (hasFrame(vid)&&i<100)
                            app.images(i).cdata = readFrame(vid);
                            i = i+1;
                        end
                        i = i-1;
                    else
                        error = true;
                    end
                end
                
                if ~error
                    app.filename = FileName;
                    app.no_images = i;
                    app.current_image = 1;
                    app.ellipses = cell(app.no_images,1);
                    update_frame_image(app);
                else
                    errordlg('Image could not be found or loaded. Make sure to only submit supported images or video files','Error');
                    app.delete;
                end
            end
            if app.no_images>1
                app.Slider.Limits=[1 app.no_images];
            else
                app.Slider.Limits=[0 app.no_images];
            end
            if app.no_images<10
                app.Slider.MajorTicks=[0:1:app.no_images];
                app.Slider.MinorTicks=app.Slider.MajorTicks;
            elseif app.no_images<10
                app.Slider.MajorTicks=[0:2:app.no_images];
                app.Slider.MinorTicks=[0:1:app.no_images];
            elseif app.no_images<20
                app.Slider.MajorTicks=[0:2:app.no_images];
                app.Slider.MinorTicks=[0:1:app.no_images];
            else
                app.Slider.MajorTicks=[0:10:app.no_images];
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            if app.no_images>1
                app.Slider.Limits=[1 app.no_images];
            else
                app.Slider.Limits=[0 app.no_images];
            end
            value = app.Slider.Value;
            
            currentFrame = int64(round(value));
            
            if currentFrame < 1
                currentFrame = 1;
            elseif currentFrame > app.no_images
                currentFrame = int64(app.no_images);
            end
            app.current_image = currentFrame;
            update_frame_image(app);
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            currentFrame = app.current_image;
            
            if currentFrame < app.no_images
                app.current_image = app.current_image+1;
                update_frame_image(app)
            end
        end

        % Button pushed function: xButton_2
        function xButton_2Pushed(app, event)
            currentFrame = app.current_image;
            
            if currentFrame + 10 < app.no_images
                app.current_image = app.current_image+10;
                update_frame_image(app)
   
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            currentFrame = app.current_image;
            
            if currentFrame > 1
                app.current_image = app.current_image-1;
                update_frame_image(app)

            end
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            currentFrame = app.current_image;
            
            if currentFrame > 10
                app.current_image = app.current_image-10;
                update_frame_image(app)

            end
        end

        % Button pushed function: SelectBubblesButton
        function SelectBubblesButtonPushed(app, event)
            fh = figure('KeyPressFcn',@Key_Down,'CloseRequestFcn',@my_closereq);
            set(fh,'renderer','opengl');
            aH = axes('Parent',fh);
            tempimage = padarray(app.images(app.current_image).cdata,[20 20],255,'both');
            imshow(tempimage, 'Parent', aH);
            hold on;
            zoom(fh);
            zoom off;
            startWidth = str2double(app.ellipseWEditField.Value);
            startHeight = str2double(app.ellipseHEditField.Value);
            iE = 0;
            hE =  cell(0);
            vol_ellipse=@(a,b) pi*a*b;
            vol_ellipse_norm = @(vol_ellipse) vol_ellipse./max(vol_ellipse);
            vol_ellipses = [];
            histogram(vol_ellipse_norm(vol_ellipses),'Parent',app.UIAxes);
            set(app.UIAxes,'visible','on');
            axis(app.UIAxes, 'normal');
            axis(app.UIAxes, 'tight');
            
            if ~isempty(app.ellipses{app.current_image})
                for iE = 1:1:size(app.ellipses{app.current_image},1)
                    Center = app.ellipses{app.current_image}(iE).Center;
                    SemiAxes = app.ellipses{app.current_image}(iE).SemiAxes;
                    RotationAngle = app.ellipses{app.current_image}(iE).RotationAngle;
                    hE{iE} =  drawellipse(aH,'Center',Center,'SemiAxes',SemiAxes,'RotationAngle',RotationAngle,'StripeColor','g');
                    
                    vol_ellipses(iE) = vol_ellipse(SemiAxes(1),SemiAxes(2));
                end
            end
            
            function Key_Down(src,event)
                user_var=event.Key;
                
                switch user_var
                    
                    case 'space'
                        histogram(vol_ellipses,15,'Parent',app.UIAxes);
                        
                        iE = iE + 1;
                        cp = get(aH,'CurrentPoint');
                        
                        hE{iE} =  drawellipse(aH,'Center',[cp(1,1),cp(1,2)],'SemiAxes',[startWidth,startHeight],'StripeColor','r');
                        
                        
                        for i = 1:1:iE
                            vol_ellipses(i) = vol_ellipse(hE{i}.SemiAxes(1),hE{i}.SemiAxes(2));
                        end
                        
                    case 'backspace'
                        if iE > 0 && ~isempty(hE{iE})
                            cp = get(aH,'CurrentPoint');
                            for i=1:1:iE
                                hE{i}.Center(1);
                                distances(i) = sqrt((cp(1,1)-hE{i}.Center(1)).^ 2 + (cp(1,2)-hE{i}.Center(2)) .^ 2);
                            end
                            [~,index]=min(distances);
                            delete(hE{index});
                            hE{index}=[];
                            hEnew = hE(~cellfun(@isempty, hE));
                            hE=hEnew;
                            iE = iE - 1;
                            vol_ellipses=vol_ellipses(1:1:iE);
                            histogram(vol_ellipses,15,'Parent',app.UIAxes);
                        end
                    case 'return'
                        if iE > 0
                            %                                 final_ellipses = repmat(struct('Center', zeros(1,2), 'SemiAxes',zeros(1,2),'RotationAngle', 0), iE, 1);
                            final_ellipses = [];
                            for i = 1:1:iE
                                final_ellipses(i).Center = hE{i}.Center;
                                final_ellipses(i).SemiAxes = hE{i}.SemiAxes;
                                final_ellipses(i).RotationAngle = hE{i}.RotationAngle;
                                final_ellipses(i).Vertices = hE{i}.Vertices;
                            end
                            
                            app.ellipses{app.current_image} = final_ellipses(:);
                            update_frame_image(app);
                            delete(fh);
                            disp('Ellipse determination ended');
                        else
                            app.ellipses{app.current_image} = [];
                            update_frame_image(app);
                            delete(fh);
                            disp('Ellipse determination ended');
                        end
                    otherwise
                        disp(user_var);
                end
            end
            
            function  my_closereq(src,callbackdata)
                % Close request function
                % to display a question dialog box
                selection = questdlg('Close This Figure Without Saving? (Press Enter to close and save...)',...
                    'Close Request Function',...
                    'Yes','No','Yes');
                switch selection
                    case 'Yes'
                        update_frame_image(app);
                        delete(fh);
                    case 'No'
                        return
                end
            end


        end

        % Callback function
        function CurrentEditFieldValueChanged(app, event)
            value = str2double(app.CurrentEditField.Value);
            if value > app.no_images
                app.current_image = app.no_images;
                app.CurrentEditField.Value = num2str(app.no_images);
            elseif value < 1
                app.current_image = 1;
                app.CurrentEditField.Value = num2str(1);
            else
                app.current_image = int64(round(value));
            end
            
            update_slider(app);
            update_frame_image(app);
        end

        % Button pushed function: SaveLinesPointsButton
        function SaveLinesPointsButtonPushed(app, event)
            
            
            [file,path] = uiputfile('bubble_ellipse_data_case01.mat');
            appdata.filename = file;
            k=find(~cellfun(@isempty,app.ellipses));
            appdata.no_images = length(k);
            for j=1:length(k)
            
            appdata.ellipses{j} = app.ellipses{k(j)};
            appdata.image{j}=app.images(k(j)).cdata;
            end
            if isequal(file,0) || isequal(path,0)
                disp('User clicked Cancel.')
            else
                save(fullfile(path,file), 'appdata');
            end
        end

        % Button pushed function: LoadLinesPointsButton
        function LoadLinesPointsButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            
            if isequal(file,0)
                disp('User selected Cancel');
            else
                load(fullfile(path,file));
                if exist('appdata')
                    %if strcmp(appdata.filename,app.filename) %&& appdata.no_images == app.no_images
                    app.images=rmfield(app.images,'cdata');
                    app.ellipses = appdata.ellipses;
                    for i=1:appdata.no_images
                    app.images(i).cdata=appdata.image{i};
                    %app.images(i).cdata = readFrame(vid);
                    end
                    app.no_images=appdata.no_images;
                    %else
                    %   disp('Somethings wrong...');
                    %end
                else
                    disp('No data to load');
                end
            end
app.current_image=1;
            update_frame_image(app);
        end

        % Button pushed function: SaveandExitButton
        function SaveandExitButtonPushed(app, event)
            SaveLinesPointsButtonPushed(app, event);
            app.delete;
        end

        % Button pushed function: CropImageButton
        function CropImageButtonPushed(app, event)
            fh = figure('KeyPressFcn',@Key_Down,'CloseRequestFcn',@my_closereq);
            set(fh,'renderer','opengl');
            aH = axes('Parent',fh);
            imshow(app.images(app.current_image).cdata, 'Parent', aH);
            app.images(app.current_image).cdata=imcrop(app.images(app.current_image).cdata);
            function Key_Down(src,event)
                user_var=event.Key;
                
                switch user_var
                    
                    
                    case 'return'
                        update_frame_image(app);
                        delete(fh);
                        
                        
                end
            end
            function  my_closereq(src,callbackdata)
                % Close request function
                update_frame_image(app);
                delete(fh);
                
                
            end
        end

        % Button pushed function: CreateTrainingDataSetsButton
        function CreateTrainingDataSetsButtonPushed(app, event)
            
            
            imnum=1;
            imval=1;
            names = {'imageFilename';'Bubble'};
            Bubbles_tot =table('Size',[0 2],'VariableTypes',{'char','cell'},'VariableNames',names);
            for frames=1:app.no_images
                
                app.current_image=frames;
                if isempty(app.ellipses{app.current_image})
                    continue
                end
                
                final_ellipses=app.ellipses{app.current_image};
                
                for i=1:length(final_ellipses)
                    final_ellipses(i).Vertices=final_ellipses(i).Vertices-20;
                    final_ellipses(i).Center=final_ellipses(i).Center-20;
                end
                %Create Training data for Faster RCNN
                
                scalefactor=1;
                [imheight,imwidth]=size(app.images(app.current_image).cdata);
                for i=1:length(final_ellipses)
                    bbox(i,1)=floor(max(min(final_ellipses(i).Vertices(:,1))*scalefactor,1));
                    bbox(i,2)=floor(max(min(final_ellipses(i).Vertices(:,2))*scalefactor,1));
                    bbox(i,3)=ceil(min(max(final_ellipses(i).Vertices(:,1))*scalefactor-bbox(i,1),imwidth-1-bbox(i,1)));
                    bbox(i,4)=ceil(min(max(final_ellipses(i).Vertices(:,2))*scalefactor-bbox(i,2),imheight-1-bbox(i,2)));
                end
                edg = insertShape((app.images(app.current_image).cdata),'rectangle',bbox, 'LineWidth', 2);
                imshow(edg)
                %Cropping Augmentation
                I=app.images(app.current_image).cdata;
                myname=sprintf('TrainimageFasterRCNN_%04d_%04d.jpg',1,frames);
                imwrite(I,myname);
                C{1}=double(bbox);
                Bubbles=table({myname},C,'VariableNames',names);
                Bubbles_tot=[Bubbles_tot;Bubbles];
                for j=2:25
                    I=app.images(app.current_image).cdata;
                    bboxes=bbox;
                    [n,m]=size(I);
                    croppingfactor=floor(n/3);
                    xstart=randi(floor(n/3));
                    ystart=min(floor(m/3),xstart);
                    xend=min(xstart+randi([300 550]),n-floor(n/10));
                    yend=min(ystart+randi([300 550]),m-floor(m/10));
                    I=I(xstart:xend,ystart:yend);
                    [m,n]=size(I);
                    bboxes(:,2)=bboxes(:,2)-xstart;
                    bboxes(:,1)=bboxes(:,1)-ystart;
                    a=find(bboxes(:,2)+bboxes(:,4)<1);
                    b=find(bboxes(:,1)+bboxes(:,3)<1);
                    c=find(bboxes(:,2)>m);
                    d=find(bboxes(:,1)>n);
                    e=union(union(union(a,b),c),d);
                    bboxesnew=bboxes;
                    bboxes(e,:)=[];
                    a=find(bboxes(:,2)<1);
                    bboxes(a,4)=bboxes(a,4)+bboxes(a,2)-1;
                    bboxes(a,2)=1;
                    
                    b=find(bboxes(:,1)<1);
                    bboxes(b,3)=bboxes(b,3)+bboxes(b,1)-1;
                    bboxes(b,1)=1;
                    
                    c=find(bboxes(:,2)+bboxes(:,4)>m);
                    bboxes(c,4)=m-bboxes(c,2);
                    d=find(bboxes(:,1)+bboxes(:,3)>n);
                    bboxes(d,3)=n-bboxes(d,1);
                    
                    a=find(bboxes(:,3)<max(5,n/100));
                    b=find(bboxes(:,4)<max(5,m/100));
                    e=union(a,b);
                    bboxes(e,:)=[];
                    
                    if randi(2)==1
                        I=I-randi(30)+randi(30);
                    end
                    
                    edg = insertShape((I),'rectangle',bboxes, 'LineWidth', 2);
                    imshow(edg);


                    myname=sprintf('TrainimageFasterRCNN_%04d_%04d.jpg',j,frames);
                    imwrite(I,myname);
                    C{1}=double(bboxes);
                    Bubbles=table({myname},C,'VariableNames',names);
                    Bubbles_tot=[Bubbles_tot;Bubbles];
                end
                
                
                %%
                %Create Training data for Regression CNN
                %% for RCNN+Regressor
                
                [imwidth,imheight]=size(app.images(app.current_image).cdata);
                
                posindexes=[1,2];
                for i=1:length(final_ellipses)
                    
                    for augmentationcounter=1:100
                        z=final_ellipses(i).Center;
                        a=(final_ellipses(i).SemiAxes(1));
                        baxis=(final_ellipses(i).SemiAxes(2));
                        phi=deg2rad(final_ellipses(i).RotationAngle);
                        b=i;
                        divfactor=2;
                        bbox(i,1)=max(1,floor(z(1)-a))+randi(floor(a/divfactor))-randi(floor(a/divfactor));
                        bbox(i,2)=floor(z(2)-baxis)+randi(floor(baxis/divfactor))-randi(floor(baxis/divfactor));
                        bbox(i,3)=ceil(2*a)+randi(floor(a/divfactor))-randi(floor(a/divfactor));
                        bbox(i,4)=ceil(2*baxis)+randi(floor(baxis/divfactor))-randi(floor(baxis/divfactor));
                        %wind=I(bbox(2):bbox(2)+bbox(4),bbox(1):bbox(1)+bbox(3));
                        bboxes=bbox;
                        newbox(b,:)=bbox(i,:);
                        [m,index]=max(bboxes(b,3:4));
                        diff=abs(bbox(b,3)-bboxes(b,4));
                        factor=m/64;
                        newbox(b,find(posindexes~=index))=max(bboxes(b,find(posindexes~=index))-round(diff/2),1);
                        newbox(b,3:4)=m;
                        
                        if newbox(b,1)+newbox(b,3)>imheight
                            newbox(b,1)=newbox(b,1)-(newbox(b,1)+newbox(b,3)-imheight);
                        end
                        if newbox(b,1)<1
                            newbox(b,1)=1;
                        end
                        if newbox(b,2)+newbox(b,4)>imwidth
                            newbox(b,2)=newbox(b,2)-(newbox(b,2)+newbox(b,4)-imwidth);
                        end
                        if newbox(b,2)<1
                            newbox(b,2)=1;
                        end
                        wind=app.images(app.current_image).cdata(newbox(b,2):newbox(b,2)+newbox(b,4),newbox(b,1):newbox(b,1)+newbox(b,3));
                        
                        
                        wind=imresize(wind,[64 64]);
                        wind=wind+randi(40)-randi(40);
                        if randi(10)==2
                            XValidation(:,:,:,imval)=uint8(wind);
                            YValidation(imval,1:2)=(z.*scalefactor-newbox(b,1:2))./factor;
                            YValidation(imval,3)=a/factor*scalefactor;
                            YValidation(imval,4)=baxis/factor*scalefactor;
                            YValidation(imval,5)=-phi;
                            imval=imval+1;
                        else
                            XTrain(:,:,:,imnum)=uint8(wind);
                            YTrain(imnum,1:2)=(z.*scalefactor-newbox(b,1:2))./factor;
                            YTrain(imnum,3)=a/factor*scalefactor;
                            YTrain(imnum,4)=baxis/factor*scalefactor;
                            YTrain(imnum,5)=-phi;
                            imnum=imnum+1;
                        end
                        %   else
                        %warning('Error');
                    end
                end
                
                
            end
            save('TrainingdataFasterRCNN.mat','Bubbles_tot','-v7.3');
            save('TrainingdataRegressor_64x64.mat','XTrain','XValidation','YTrain','YValidation','-v7.3');
        end

        % Button pushed function: 
        % UsePrelabellingTraditionalImagingButton
        function UsePrelabellingTraditionalImagingButtonPushed(app, event)
            clear hE iE
            
            
            I=app.images(app.current_image).cdata;
            [imsizex,imsizey]=size(I);
            Kmedian = medfilt2(I);
            [Gmag,Gdir] = imgradient(Kmedian);
            Gmag = imsegkmeans(uint8(Gmag),2,'NumAttempts',3,'NormalizeInput',true);
            [counts] = hist(Gmag,[1 2]);
            
            Gmag=imbinarize(Gmag);
            if sum(counts(1,:))<sum(counts(2,:))
                Gmag=imcomplement(Gmag);
            end
            Gmag=bwareaopen(Gmag,min(max(floor((imsizex*imsizey)/3000),50),100),4);%fill in the 'holes'in the bubbles
            Gmag=imfill(Gmag,'holes');
            
            boundaries=bwboundaries(Gmag);
            indexes=find(cellfun('length',boundaries)<30);
            boundaries((indexes),:)=[];
            iE = 0;
            hE =  cell(0);
            fh = figure('KeyPressFcn',@Key_Down);
            set(fh,'renderer','opengl');
            aH = axes('Parent',fh);
            imshow(I, 'Parent', aH);
            
            if ~isempty(app.ellipses{app.current_image})
                for iE = 1:1:size(app.ellipses{app.current_image},1)
                    Center = app.ellipses{app.current_image}(iE).Center-20;
                    SemiAxes = app.ellipses{app.current_image}(iE).SemiAxes;
                    RotationAngle = app.ellipses{app.current_image}(iE).RotationAngle;
                    hE{iE} =  drawellipse(aH,'Center',Center,'SemiAxes',SemiAxes,'RotationAngle',RotationAngle,'StripeColor','g');
                    
                    
                end
            end
            
            for i=1:length(boundaries)
                try
                    tempellipse=fit_ellipse(boundaries{i}(:,1),boundaries{i}(:,2));
                    z=[tempellipse.Y0_in,tempellipse.X0_in]';
                    a=tempellipse.b;
                    b=tempellipse.a;
                    alpha=wrapTo360(-rad2deg(tempellipse.phi));
                    iE = iE + 1;
                    hE{iE} =drawellipse('Center',[z(1),z(2)],'SemiAxes',[a,b],'RotationAngle',(alpha),'StripeColor','r');
                catch ME
                    
                    
                end
                
            end
            if iE > 0
                
                final_ellipses = [];
                for i = 1:1:iE
                    final_ellipses(i).Center = hE{i}.Center+20;
                    final_ellipses(i).SemiAxes = hE{i}.SemiAxes;
                    final_ellipses(i).RotationAngle = hE{i}.RotationAngle;
                    final_ellipses(i).Vertices = hE{i}.Vertices+20;
                end
                
                app.ellipses{app.current_image} = final_ellipses(:);
                update_frame_image(app);
                delete(fh);
                disp('Ellipse determination ended');
            else
                app.ellipses{app.current_image} = [];
                update_frame_image(app);
                delete(fh);
                disp('Ellipse determination ended');
            end
            
            
        end

        % Button pushed function: 
        % UsePrelabellingPrevBubCNNVersionButton
        function UsePrelabellingPrevBubCNNVersionButtonPushed(app, event)
            [file,path]=uigetfile('*.mat','Load Faster RCNN Module');
            Transfermodule=fullfile(path,file);
            load(Transfermodule);
            [file,path]=uigetfile('*.mat','Load Shape Regression Module');
            Shapemodule=fullfile(path,file);
            load(Shapemodule);
            
            regressornet=trainedNet;
            I=app.images(app.current_image).cdata;
            fh = figure('KeyPressFcn',@Key_Down);
            set(fh,'renderer','opengl');
            aH = axes('Parent',fh);
            imshow(I, 'Parent', aH);
            f = uifigure;
            d = uiprogressdlg(f,'Title','Please Wait',...
                'Message','Run Faster RCNN module');
            iE = 0;
            hE =  cell(0);
            if ~isempty(app.ellipses{app.current_image})
                for iE = 1:1:size(app.ellipses{app.current_image},1)
                    Center = app.ellipses{app.current_image}(iE).Center-20;
                    SemiAxes = app.ellipses{app.current_image}(iE).SemiAxes;
                    RotationAngle = app.ellipses{app.current_image}(iE).RotationAngle;
                    hE{iE} =  drawellipse(aH,'Center',Center,'SemiAxes',SemiAxes,'RotationAngle',RotationAngle,'StripeColor','g');
                    
                end
            end
            [imheight,imwidth,depth]=size(I);
            if depth<3
                I=repmat(I,1,1,3);
            end

    [bboxes,scores] = detect(owndetector,I,'SelectStrongest',false,'NumStrongestRegions',Inf);
        if ~isempty(scores)
            [bboxes2,scores] = selectStrongestBbox(bboxes, scores,'OverlapThreshold',0.5);
        end
        
        [boxlength,~]=size(bboxes2);
        newbox=bboxes2;
        posindexes=[1,2];
        for b=1:boxlength
            [m,index]=max(bboxes2(b,3:4));
            diff=abs(bboxes2(b,3)-bboxes2(b,4));
            factor=m/64;
            newbox(b,find(posindexes~=index))=max(bboxes2(b,find(posindexes~=index))-round(diff/2),1);
            newbox(b,3:4)=m;
            if newbox(b,1)+newbox(b,3)>imwidth
                newbox(b,1)=newbox(b,1)-(newbox(b,1)+newbox(b,3)-imwidth);
            end
            if newbox(b,1)<1
                newbox(b,1)=1;
            end
            if newbox(b,2)+newbox(b,4)>imheight
                newbox(b,2)=newbox(b,2)-(newbox(b,2)+newbox(b,4)-imheight);
            end
            if newbox(b,2)<1
                newbox(b,2)=1;
            end
            wind=I(newbox(b,2):newbox(b,2)+newbox(b,4),newbox(b,1):newbox(b,1)+newbox(b,3));
            wind=imresize(wind,[64 64]);
            Bubbles(b,1:6)=(predict(regressornet,wind)).*(S)+mumean;
            
            Bubbles(b,1:4)=Bubbles(b,1:4).*factor;
            Bubbles(b,1)=Bubbles(b,1)+newbox(b,1);
            Bubbles(b,2)=Bubbles(b,2)+newbox(b,2);
            Bubbles(b,5)=atan(Bubbles(b,5)/Bubbles(b,6))/2;
            alpha=wrapTo360(-rad2deg(Bubbles(b,5)));
            
            iE = iE + 1;
            hE{iE} =drawellipse('Center',[Bubbles(b,1),Bubbles(b,2)],'SemiAxes',[Bubbles(b,3),Bubbles(b,4)],'RotationAngle',(alpha),'StripeColor','r');
            d.Value = b/boxlength;
            d.Message = 'Run shape regression CNN';
            
        end


        if iE > 0
            final_ellipses = [];
            for i = 1:1:iE
                final_ellipses(i).Center = hE{i}.Center+20;
                final_ellipses(i).SemiAxes = hE{i}.SemiAxes;
                final_ellipses(i).RotationAngle = hE{i}.RotationAngle;
                final_ellipses(i).Vertices = hE{i}.Vertices+20;
            end
            
            app.ellipses{app.current_image} = final_ellipses(:);
            update_frame_image(app);
            delete(fh);
            delete(f);
            disp('Ellipse determination ended');
        else
            app.ellipses{app.current_image} = [];
            update_frame_image(app);
            delete(fh);
            delete(f);
            disp('Ellipse determination ended');
        end


        end

        % Button pushed function: StartTransferLearningButton
        function StartTransferLearningButtonPushed(app, event)
    load('TrainingdataRegressor_64x64.mat');
    data=load('TrainingdataFasterRCNN.mat');
    
    [file,path]=uigetfile('*.mat','Load Shape Regression Module');
    Shapemodule=fullfile(path,file);
    load(Shapemodule);
    temp=data.Bubbles_tot;
    for i=1:height(data.Bubbles_tot)
        I=imread(data.Bubbles_tot.imageFilename{i});
        I=repmat (I,1,1,3);
        name=sprintf('temp%d.jpg',i);
        imwrite(I,name);
        temp.imageFilename{i}=name;
    end
    data.Bubbles_tot=temp;
    data.Bubbles_tot = data.Bubbles_tot(randperm(height(data.Bubbles_tot)), :);
    Bubbles=data.Bubbles_tot(1:floor(height(data.Bubbles_tot)*0.75),:);
    Validationdata=data.Bubbles_tot(floor(height(data.Bubbles_tot)*0.75)+1:end,:);
    %Angle Encoding
    YTrain=horzcat(YTrain(:,1:4),sin(2.*YTrain(:,5)),cos(2.*YTrain(:,5)));
    YValidation=horzcat(YValidation(:,1:4),sin(2.*YValidation(:,5)),cos(2.*YValidation(:,5)));
    %%
    %Feature Scaling
    miny=min(YTrain);
    maxy=max(YTrain);
    mumean=median(YTrain);
    S=std(YTrain);
    %Standardization
    YTrain=((YTrain-mumean)./(S));
    YValidation=((YValidation-mumean)./(S));


    miniBatchSize = 128;
    validationFrequency = floor(numel(YTrain)/miniBatchSize);
    options = trainingOptions('sgdm', ...
        'InitialLearnRate',10^-4, ...
        'MaxEpochs',300, ...
        'LearnRateSchedule','piecewise', ...
        'LearnRateDropPeriod',20, ...
        'LearnRateDropFactor',0.7, ...
        'MiniBatchSize',miniBatchSize, ...
        'L2Regularization',10^-5, ...
        'Shuffle','every-epoch', ...
        'Verbose',true, ...
        'Plots','none', ...
        'ValidationData',{XValidation,YValidation}, ...
        'ValidationFrequency',validationFrequency);
    
    
    trainedNet = trainNetwork(XTrain,YTrain,trainedNet.Layers,options);
    save('Transferregressor.mat','trainedNet','S','mumean');
    YPredicted = predict(trainedNet,XValidation);
    YPredicted=YPredicted.*(S)+mumean;
    YValid=YValidation.*(S)+mumean;
    predictionError = abs(YValid - YPredicted)./max(0.5,abs(YValid));
    tempval=trimmean(predictionError,5)
    valError=mean(trimmean(predictionError,5))
    YPredictedTrain = predict(trainedNet,XTrain);
    YPredictedTrain=YPredictedTrain.*(S)+mumean;
    %YPredicted=horzcat(YPredicted(:,1:4),2.*atand(YPredicted(:,5)./YPredicted(:,6)));
    YTr=YTrain.*(S)+mumean;
    predictionErrorTrain = abs(YTr - YPredictedTrain)./max(0.5,abs(YTr));
    tempval=trimmean(predictionErrorTrain,5)
    valErrorTrain=mean(trimmean(predictionErrorTrain,5))
    
    
    oldnetwork=trainedNet;
    layersTransfer=oldnetwork.Layers(1:end-2);
    
    layers = [
        layersTransfer
        fullyConnectedLayer(2,'WeightLearnRateFactor',20,'BiasLearnRateFactor',20)
        softmaxLayer
        classificationLayer];
    
    
    anzepochs=100;
    learnrate=10^-4;
    
    optionsStage1 = trainingOptions('adam', ...
        'LearnRateSchedule', 'piecewise', ...
        'MaxEpochs', anzepochs*2, ...
        'MiniBatchSize', 1, ...
        'InitialLearnRate',learnrate , ...
        'LearnRateDropPeriod',20,...
        'LearnRateDropFactor',0.5,...
        'ExecutionEnvironment','auto','Shuffle','every-epoch','L2Regularization',10^-8);
    
    % Options for step 2.
    optionsStage2 = trainingOptions('adam', ...
        'LearnRateSchedule', 'piecewise', ...
        'MaxEpochs', anzepochs, ...
        'MiniBatchSize', 1, ...
        'InitialLearnRate', learnrate, ...
        'LearnRateDropPeriod',10,...
        'LearnRateDropFactor',0.5,...
        'ExecutionEnvironment','auto','Shuffle','every-epoch','L2Regularization',10^-8);
    
    % Options for step 3.
    optionsStage3 = trainingOptions('adam', ...
        'LearnRateSchedule', 'piecewise', ...
        'MaxEpochs', anzepochs*2, ...
        'MiniBatchSize', 1, ...
        'InitialLearnRate', learnrate, ...
        'LearnRateDropPeriod',20,...
        'LearnRateDropFactor',0.5,...
        'ExecutionEnvironment','auto','Shuffle','every-epoch','L2Regularization',10^-8);
    
    % Options for step 4.
    optionsStage4 = trainingOptions('adam', ...
        'LearnRateSchedule', 'piecewise', ...
        'MaxEpochs', anzepochs, ...
        'MiniBatchSize', 1, ...
        'InitialLearnRate', learnrate, ...
        'LearnRateDropPeriod',10,...
        'LearnRateDropFactor',0.5,...
        'ExecutionEnvironment','auto','Shuffle','every-epoch','L2Regularization',10^-8);
    %%
    options = [
        optionsStage1
        optionsStage2
        optionsStage3
        optionsStage4
        ];
    
    [owndetector]=trainFasterRCNNObjectDetector(Bubbles,layers,optionsStage1,'NegativeOverlapRange',[0 0.3],'PositiveOverlapRange',[0.5 1],'NumStrongestRegions',Inf,'NumRegionsToSample',128,'SmallestImageDimension',500);
    save('Transferdetector.mat','owndetector');
    for val = 1:height(Validationdata)
        I = imread(Validationdata.imageFilename{val});
        [m,n]=size(I);
        if (or(m<64,n<64))
            continue
        end
        [bboxes,scores] = detect(owndetector,I,'SelectStrongest',false);
        if ~isempty(scores)
            [bboxes,scores] = selectStrongestBbox(bboxes, scores,'OverlapThreshold',0.5);
        end
        Validationresults(val).Boxes = bboxes;
        Validationresults(val).Scores = scores;
    end
    Validationresults = struct2table(Validationresults);
    
    [averagePrecision,~,~]=evaluateDetectionPrecision(Validationresults,Validationdata(:,2));
    [precision,recall] = bboxPrecisionRecall(Validationresults(:,1),Validationdata(:,2));
    averagePrecision(1)
    mrec=mean(recall)
    mpr=mean(precision)
    F1(1)=1-((2*mpr*mrec)/(mpr+mrec));
    F1(1)
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
    open('BubCNN_TransModule_Manual.pdf');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BubCNN_Transfer_Learning_Module = uifigure('Visible', 'off');
            app.BubCNN_Transfer_Learning_Module.Name = 'BubCNN Transfer Learning Module';
            app.GridLayout = uigridlayout(app.BubCNN_Transfer_Learning_Module);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.Slider = uislider(app.GridLayout);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.SaveandExitButton = uibutton(app.GridLayout, 'push');
            app.SaveandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandExitButtonPushed, true);
            app.SaveandExitButton.Text = 'Save and Exit';
            app.TippsPanel = uipanel(app.GridLayout);
            app.TippsPanel.Title = 'Tipps';
            app.GridLayout3 = uigridlayout(app.TippsPanel);
            app.TextAreaLabel = uilabel(app.GridLayout3);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.GridLayout3);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'1. Click ''Select Bubbles'' to start labeling'; '2. Click on a bubble '; '3. Press space to create an ellipse.'; '4. Modify ellipe'; '(5.) Backspace to delete ellipse'; '6. repeat 2-4(5) '; '7. Press enter to close and save'; ''};
            app.SaveLinesPointsButton = uibutton(app.GridLayout, 'push');
            app.SaveLinesPointsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLinesPointsButtonPushed, true);
            app.SaveLinesPointsButton.Text = 'Save Lines/Points';
            app.LoadLinesPointsButton = uibutton(app.GridLayout, 'push');
            app.LoadLinesPointsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLinesPointsButtonPushed, true);
            app.LoadLinesPointsButton.Text = {'Load Lines/Points'; ''};
            app.SelectBubblesButton = uibutton(app.GridLayout, 'push');
            app.SelectBubblesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBubblesButtonPushed, true);
            app.SelectBubblesButton.Text = {'Select Bubbles'; ''};
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '>';
            app.xButton = uibutton(app.GridLayout, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = '<10x';
            app.xButton_2 = uibutton(app.GridLayout, 'push');
            app.xButton_2.ButtonPushedFcn = createCallbackFcn(app, @xButton_2Pushed, true);
            app.xButton_2.Text = '10x>';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '<';
            app.ellipseHEditFieldLabel = uilabel(app.GridLayout);
            app.ellipseHEditFieldLabel.Text = 'ellipse H';
            app.ellipseHEditField = uieditfield(app.GridLayout, 'text');
            app.ellipseHEditField.Value = '10';
            app.ellipseWEditFieldLabel = uilabel(app.GridLayout);
            app.ellipseWEditFieldLabel.Text = 'ellipse W';
            app.ellipseWEditField = uieditfield(app.GridLayout, 'text');
            app.ellipseWEditField.Value = '20';
            app.CropImageButton = uibutton(app.GridLayout, 'push');
            app.CropImageButton.ButtonPushedFcn = createCallbackFcn(app, @CropImageButtonPushed, true);
            app.CropImageButton.Text = 'Crop Image';
            app.CreateTrainingDataSetsButton = uibutton(app.GridLayout, 'push');
            app.CreateTrainingDataSetsButton.ButtonPushedFcn = createCallbackFcn(app, @CreateTrainingDataSetsButtonPushed, true);
            app.CreateTrainingDataSetsButton.Text = 'Create Training Data Sets';
            app.UsePrelabellingTraditionalImagingButton = uibutton(app.GridLayout, 'push');
            app.UsePrelabellingTraditionalImagingButton.ButtonPushedFcn = createCallbackFcn(app, @UsePrelabellingTraditionalImagingButtonPushed, true);
            app.UsePrelabellingTraditionalImagingButton.Text = 'Use Pre-labelling (Traditional Imaging)';
            app.UsePrelabellingPrevBubCNNVersionButton = uibutton(app.GridLayout, 'push');
            app.UsePrelabellingPrevBubCNNVersionButton.ButtonPushedFcn = createCallbackFcn(app, @UsePrelabellingPrevBubCNNVersionButtonPushed, true);
            app.UsePrelabellingPrevBubCNNVersionButton.Text = 'Use Pre-labelling (Prev. BubCNN-Version)';
            app.StartTransferLearningButton = uibutton(app.GridLayout, 'push');
            app.StartTransferLearningButton.ButtonPushedFcn = createCallbackFcn(app, @StartTransferLearningButtonPushed, true);
            app.StartTransferLearningButton.Text = 'Start Transfer Learning';
            app.Logo = uiimage(app.GridLayout);
            app.Logo.ImageSource = 'rwth_iob_bild_rgb.jpg';
            app.HelpButton = uibutton(app.GridLayout, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.BubCNN_Transfer_Learning_Module.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_BubCNN_TransferModule

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BubCNN_Transfer_Learning_Module)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BubCNN_Transfer_Learning_Module)
        end
    end
end